classdef waypointPublisher < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WaypointPublisherUIFigure      matlab.ui.Figure
        Title                          matlab.ui.control.Label
        WaypointTable                  matlab.ui.control.Table
        MATLABWorkspacePanel           matlab.ui.container.Panel
        ReadVariablesButton            matlab.ui.control.Button
        XYZPointsEditFieldLabel        matlab.ui.control.Label
        XYZPointsEditField             matlab.ui.control.EditField
        WriteVariablesButton           matlab.ui.control.Button
        OrientationsEditFieldLabel     matlab.ui.control.Label
        OrientationsEditField          matlab.ui.control.EditField
        WaypointTimesEditFieldLabel    matlab.ui.control.Label
        WaypointTimesEditField         matlab.ui.control.EditField
        ROSPanel                       matlab.ui.container.Panel
        PublishButton                  matlab.ui.control.Button
        WaypointTopicLabel             matlab.ui.control.Label
        WaypointTopicEditField         matlab.ui.control.EditField
        WaypointTimeTopicEditFieldLabel  matlab.ui.control.Label
        WaypointTimeTopicEditField     matlab.ui.control.EditField
        AddButton                      matlab.ui.control.Button
        DelButton                      matlab.ui.control.Button
        UpButton                       matlab.ui.control.Button
        DownButton                     matlab.ui.control.Button
        SimulinkModelPanel             matlab.ui.container.Panel
        UpdateWaypointsButton          matlab.ui.control.Button
        ModelNameEditFieldLabel        matlab.ui.control.Label
        ModelNameEditField             matlab.ui.control.EditField
        SimulateButton                 matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        VisualizationPanel             matlab.ui.container.Panel
        ShowRobotButton                matlab.ui.control.Button
        AnimateRobotButton             matlab.ui.control.Button
        RigidBodyTreeEditFieldLabel    matlab.ui.control.Label
        RigidBodyTreeEditField         matlab.ui.control.EditField
        EndEffectorNameEditFieldLabel  matlab.ui.control.Label
        EndEffectorNameEditField       matlab.ui.control.EditField
    end

    % Waypoint Publisher App
    % Copyright 2019 The MathWorks, Inc.
    
    properties (Access = private)           
        selectedWaypoint = 1;   % Currently selected waypoint on table
        
        robot;                  % Rigid Body Tree
        robotConfig;            % Rigid Body Tree joint configuration
        robotIK;                % Rigid Body Tree Inverse Kinematics solver
        robotIKWeights;         % Rigid Body Tree Inverse Kinematics weights
        robotIKInitGuess;       % Rigid Body Tree Inverse Kinematics initial guess
        robotEE;                % Rigid Body Tree End Effector frame
        
        waypointPub;            % Waypoint ROS publisher
        waypointTimePub;        % Waypoint time ROS publisher
    end

    methods (Access = private)
    
        function createRigidBodyTree(app)
            % Creates a rigid body tree and IK solver from the robot and end effector name fields
            evalin('base',['load ' app.RigidBodyTreeEditField.Value]);
            app.robot = evalin('base',app.RigidBodyTreeEditField.Value);
            app.robot.DataFormat = 'row';
            app.robotIK = robotics.InverseKinematics('RigidBodyTree',app.robot);
            app.robotIK.SolverParameters.MaxIterations = 50;    % Limit the max iterations
            app.robotIKWeights = [1, 1, 1, 1, 1, 1];
            app.robotConfig = app.robot.homeConfiguration;
            app.robotIKInitGuess = app.robotConfig;
            app.robotEE = app.EndEffectorNameEditField.Value;    
        end
    
        function showRobot(app,idx)
            % Displays the waypoints, and the rigid body tree IK solution a provided waypoint index "idx"
            
            % Use the home configuration if the point is invalid
            if idx > size(app.WaypointTable.Data,1)
                app.robotConfig = app.robot.homeConfiguration;
            % Else, solve IK at the specified waypoint index
            else      
                position = app.WaypointTable.Data(idx,2:4);
                orient = app.WaypointTable.Data(idx,5:7);
                targetTform = trvec2tform(position)*eul2tform(orient);
                app.robotConfig = app.robotIK(app.robotEE,targetTform,app.robotIKWeights,app.robotIKInitGuess);            
            end
            % Display the rigid body tree
            show(app.robot,app.robotConfig,'Frames','off');
            app.plotWaypointMarkers(app.WaypointTable.Data,true); 
        end
    
        function plotWaypointMarkers(~,data,plotOrientations)
            % Helper method that plots waypoint markers
            hold on;
            plot3(data(:,2),data(:,3),data(:,4),'ro','MarkerSize',8,'LineWidth',2);
            if plotOrientations
               positions = data(:,2:4);
               orientations = data(:,5:7);
               plotTransforms(positions,eul2quat(orientations),'FrameSize',0.2);
            end
            hold off;
        end
        
        function exportVariablesToWorkspace(app)
            % Exports the waypoint and time data to the MATLAB base workspace.
            % Used when clicking the "To Workspace" and "Update Diagram" buttons
            assignin('base',app.WaypointTimesEditField.Value,app.WaypointTable.Data(:,1)');
            assignin('base',app.XYZPointsEditField.Value,app.WaypointTable.Data(:,2:4)');
            assignin('base',app.OrientationsEditField.Value,app.WaypointTable.Data(:,5:7)');
        end
        
        function publishWaypoints(app)
            % Send a geometry_msgs/PoseArray ROS message containing the waypoints in the table, 
            % and 2 std_msgs/Float64MultiArray ROS messages for the waypoint and accel times
            if isempty(app.waypointPub)
                try
                    app.waypointPub = rospublisher(app.WaypointTopicEditField.Value,'geometry_msgs/PoseArray');
                    app.waypointTimePub = rospublisher(app.WaypointTimeTopicEditField.Value,'std_msgs/Float64MultiArray');
                catch
                    errordlg('ROS Master not available','Info');
                    return;
                end
            end
            wpMsg = rosmessage('geometry_msgs/PoseArray');
            
            tableData = app.WaypointTable.Data;
            for idx = 1:size(tableData,1)
               poseMsg = rosmessage('geometry_msgs/Pose');
               poseMsg.Position.X = tableData(idx,2);
               poseMsg.Position.Y = tableData(idx,3);
               poseMsg.Position.Z = tableData(idx,4);
               quaternion = eul2quat(tableData(idx,5:7));
               poseMsg.Orientation.W = quaternion(1);
               poseMsg.Orientation.X = quaternion(2);
               poseMsg.Orientation.Y = quaternion(3);
               poseMsg.Orientation.Z = quaternion(4);
               wpMsg.Poses(idx) = poseMsg;
            end
            
            % Package the waypoint times into messages
            wpTimeMsg = rosmessage('std_msgs/Float64MultiArray');
            wpTimeMsg.Data = tableData(:,1);
            
            % Send the messages            
            send(app.waypointPub,wpMsg);
            send(app.waypointTimePub,wpTimeMsg);
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            % Create Rigid Body Tree
            createRigidBodyTree(app);
            
            % Populate Table with sample data
            createWaypointData;
            try
                app.WaypointTable.Data = [waypointTimes', waypoints', orientations'];
            catch
                app.WaypointTable.Data = zeros(1,7);
            end
            app.selectedWaypoint = size(waypoints,1) + 1;
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            idx = min(app.selectedWaypoint,size(app.WaypointTable.Data,1));
            app.WaypointTable.Data = [app.WaypointTable.Data(1:idx,:); ...
                                      zeros(1,7); ...
                                      app.WaypointTable.Data(idx+1:end,:)];
        end

        % Button pushed function: DelButton
        function DelButtonPushed(app, event)
            idx = app.selectedWaypoint;
            if size(app.WaypointTable.Data,1) > 1 && idx <= size(app.WaypointTable.Data,1)
                app.WaypointTable.Data(idx,:) = [];
            end
        end

        % Cell selection callback: WaypointTable
        function WaypointTableCellSelection(app, event)
            app.selectedWaypoint = event.Indices(1);
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            % Move the selected waypoint row up one spot unless it's the first one
            idx = app.selectedWaypoint;
            if idx > 1
                app.WaypointTable.Data([idx-1,idx],:) = app.WaypointTable.Data([idx,idx-1],:);
            end
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            % Move the selected waypoint down up one spot unless it's the first one
            idx = app.selectedWaypoint;
            if idx < size(app.WaypointTable.Data,1)
                app.WaypointTable.Data([idx,idx+1],:) = app.WaypointTable.Data([idx+1,idx],:);
            end           
        end

        % Button pushed function: ShowRobotButton
        function ShowRobotButtonPushed(app, event)
            % Display the Rigid Body Tree
            idx = app.selectedWaypoint;
            showRobot(app,idx);
        end

        % Cell edit callback: WaypointTable
        function WaypointTableCellEdit(app, event)
            % Evaluates a non-literal expression and sets the table data if the outcome is numeric
            data = evalin('caller',event.EditData);
            if isnumeric(data)
               app.WaypointTable.Data(event.Indices(1),event.Indices(2)) = data;
            end
        end

        % Button pushed function: AnimateRobotButton
        function AnimateRobotButtonPushed(app, event)
            % Animate one cycle of the rigid body tree going through the waypoints
            for idx = 1:size(app.WaypointTable.Data,1)
                showRobot(app,idx);
                pause(1);
            end
        end

        % Value changed function: RigidBodyTreeEditField
        function RigidBodyTreeEditFieldValueChanged(app, event)
            createRigidBodyTree(app);
        end

        % Value changed function: EndEffectorNameEditField
        function EndEffectorNameEditFieldValueChanged(app, event)
            createRigidBodyTree(app);            
        end

        % Button pushed function: PublishButton
        function PublishButtonPushed(app, event)
            app.publishWaypoints();
        end

        % Button pushed function: WriteVariablesButton
        function WriteVariablesButtonPushed(app, event)
            try
                exportVariablesToWorkspace(app);
                msgbox('Saved waypoint data to workspace','Info');
            catch
                errordlg('Failed to save waypoint data to workspace','Info');
            end
        end

        % Button pushed function: ReadVariablesButton
        function ReadVariablesButtonPushed(app, event)
           try
               waypointTimes = evalin('base',app.WaypointTimesEditField.Value);
               waypoints = evalin('base',app.XYZPointsEditField.Value);
               orientations = evalin('base',app.OrientationsEditField.Value);
               app.WaypointTable.Data = [waypointTimes', waypoints', orientations'];
               msgbox('Loaded waypoint data from workspace','Info');
           catch
               errordlg('Failed to load waypoint data from workspace','Info');
           end
        end

        % Button pushed function: UpdateWaypointsButton
        function UpdateWaypointsButtonPushed(app, event)
            exportVariablesToWorkspace(app)
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            mdlName = app.ModelNameEditField.Value;
            if ~bdIsLoaded(mdlName)
                open_system(mdlName)
            end
            set_param(app.ModelNameEditField.Value,'SimulationCommand','start');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            set_param(app.ModelNameEditField.Value,'SimulationCommand','stop');    
        end

        % Value changed function: WaypointTopicEditField
        function WaypointTopicEditFieldValueChanged(app, event)
            app.waypointPub = rospublisher(app.WaypointTopicEditField.Value,'geometry_msgs/PoseArray');            
        end

        % Value changed function: WaypointTimeTopicEditField
        function WaypointTimeTopicEditFieldValueChanged(app, event)
            app.waypointTimePub = rospublisher(app.WaypointTimeTopicEditField.Value,'std_msgs/Float64MultiArray');            
        end

        % Callback function
        function AccelTimeTopicEditFieldValueChanged(app, event)
            app.accelTimePub = rospublisher(app.AccelTimeTopicEditField.Value,'std_msgs/Float64MultiArray');                        
        end

        % Callback function
        function ToFileButtonPushed(app, event)
        % Save waypoint information to a file
            try
                assignin('caller',app.WaypointTimesEditField.Value,app.WaypointTable.Data(:,1));
                assignin('caller',app.XYZPointsEditField.Value,app.WaypointTable.Data(:,2:4));
                assignin('caller',app.OrientationsEditField.Value,app.WaypointTable.Data(:,5:7));
                save(filename,app.WaypointTimesEditField.Value,app.XYZPointsEditField.Value, app.OrientationsEditField.Value);
                msgbox('Saved waypoint data to file','Info');
            catch
                errordlg('Failed to save waypoint data to workspace','Info');
            end          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WaypointPublisherUIFigure = uifigure('Visible', 'off');
            app.WaypointPublisherUIFigure.Name = 'Waypoint Publisher';
            app.Title = uilabel(app.WaypointPublisherUIFigure);
            app.Title.Text = 'Table of Waypoints';
            app.WaypointTable = uitable(app.WaypointPublisherUIFigure);
            app.WaypointTable.ColumnName = {'Time'; 'X'; 'Y'; 'Z'; 'Yaw'; 'Pitch'; 'Roll'};
            app.WaypointTable.RowName = {};
            app.WaypointTable.ColumnEditable = true;
            app.WaypointTable.CellEditCallback = createCallbackFcn(app, @WaypointTableCellEdit, true);
            app.WaypointTable.CellSelectionCallback = createCallbackFcn(app, @WaypointTableCellSelection, true);
            app.MATLABWorkspacePanel = uipanel(app.WaypointPublisherUIFigure);
            app.MATLABWorkspacePanel.Title = 'MATLAB Workspace';
            app.ReadVariablesButton = uibutton(app.MATLABWorkspacePanel, 'push');
            app.ReadVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @ReadVariablesButtonPushed, true);
            app.ReadVariablesButton.Text = 'Read Variables';
            app.XYZPointsEditFieldLabel = uilabel(app.MATLABWorkspacePanel);
            app.XYZPointsEditFieldLabel.Text = 'XYZ Points';
            app.XYZPointsEditField = uieditfield(app.MATLABWorkspacePanel, 'text');
            app.XYZPointsEditField.Value = 'waypoints';
            app.WriteVariablesButton = uibutton(app.MATLABWorkspacePanel, 'push');
            app.WriteVariablesButton.ButtonPushedFcn = createCallbackFcn(app, @WriteVariablesButtonPushed, true);
            app.WriteVariablesButton.Text = 'Write Variables';
            app.OrientationsEditFieldLabel = uilabel(app.MATLABWorkspacePanel);
            app.OrientationsEditFieldLabel.Text = 'Orientations';
            app.OrientationsEditField = uieditfield(app.MATLABWorkspacePanel, 'text');
            app.OrientationsEditField.Value = 'orientations';
            app.WaypointTimesEditFieldLabel = uilabel(app.MATLABWorkspacePanel);
            app.WaypointTimesEditFieldLabel.Text = 'Waypoint Times';
            app.WaypointTimesEditField = uieditfield(app.MATLABWorkspacePanel, 'text');
            app.WaypointTimesEditField.Value = 'waypointTimes';
            app.ROSPanel = uipanel(app.WaypointPublisherUIFigure);
            app.ROSPanel.Title = 'ROS';
            app.PublishButton = uibutton(app.ROSPanel, 'push');
            app.PublishButton.ButtonPushedFcn = createCallbackFcn(app, @PublishButtonPushed, true);
            app.PublishButton.Text = 'Publish';
            app.WaypointTopicLabel = uilabel(app.ROSPanel);
            app.WaypointTopicLabel.Text = 'Waypoint Topic';
            app.WaypointTopicEditField = uieditfield(app.ROSPanel, 'text');
            app.WaypointTopicEditField.ValueChangedFcn = createCallbackFcn(app, @WaypointTopicEditFieldValueChanged, true);
            app.WaypointTopicEditField.Value = '/waypoints';
            app.WaypointTimeTopicEditFieldLabel = uilabel(app.ROSPanel);
            app.WaypointTimeTopicEditFieldLabel.Text = 'Waypoint Time Topic';
            app.WaypointTimeTopicEditField = uieditfield(app.ROSPanel, 'text');
            app.WaypointTimeTopicEditField.ValueChangedFcn = createCallbackFcn(app, @WaypointTimeTopicEditFieldValueChanged, true);
            app.WaypointTimeTopicEditField.Value = '/waypoint_times';
            app.AddButton = uibutton(app.WaypointPublisherUIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = '+';
            app.DelButton = uibutton(app.WaypointPublisherUIFigure, 'push');
            app.DelButton.ButtonPushedFcn = createCallbackFcn(app, @DelButtonPushed, true);
            app.DelButton.Text = '-';
            app.UpButton = uibutton(app.WaypointPublisherUIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = '^';
            app.DownButton = uibutton(app.WaypointPublisherUIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'v';
            app.SimulinkModelPanel = uipanel(app.WaypointPublisherUIFigure);
            app.SimulinkModelPanel.Title = 'Simulink Model';
            app.UpdateWaypointsButton = uibutton(app.SimulinkModelPanel, 'push');
            app.UpdateWaypointsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateWaypointsButtonPushed, true);
            app.UpdateWaypointsButton.Text = {'Update '; 'Waypoints'};
            app.ModelNameEditFieldLabel = uilabel(app.SimulinkModelPanel);
            app.ModelNameEditFieldLabel.Text = 'Model Name';
            app.ModelNameEditField = uieditfield(app.SimulinkModelPanel, 'text');
            app.ModelNameEditField.Value = 'manipTransformTrajectory';
            app.SimulateButton = uibutton(app.SimulinkModelPanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.StopButton = uibutton(app.SimulinkModelPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.VisualizationPanel = uipanel(app.WaypointPublisherUIFigure);
            app.VisualizationPanel.Title = 'Visualization';
            app.ShowRobotButton = uibutton(app.VisualizationPanel, 'push');
            app.ShowRobotButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRobotButtonPushed, true);
            app.ShowRobotButton.Text = {'Show Robot at '; 'Selected Point'};
            app.AnimateRobotButton = uibutton(app.VisualizationPanel, 'push');
            app.AnimateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @AnimateRobotButtonPushed, true);
            app.AnimateRobotButton.Text = {'Animate Robot '; 'at All Points'};
            app.RigidBodyTreeEditFieldLabel = uilabel(app.VisualizationPanel);
            app.RigidBodyTreeEditFieldLabel.Text = 'Rigid Body Tree';
            app.RigidBodyTreeEditField = uieditfield(app.VisualizationPanel, 'text');
            app.RigidBodyTreeEditField.ValueChangedFcn = createCallbackFcn(app, @RigidBodyTreeEditFieldValueChanged, true);
            app.RigidBodyTreeEditField.Value = 'gen3';
            app.EndEffectorNameEditFieldLabel = uilabel(app.VisualizationPanel);
            app.EndEffectorNameEditFieldLabel.Text = 'End Effector Name';
            app.EndEffectorNameEditField = uieditfield(app.VisualizationPanel, 'text');
            app.EndEffectorNameEditField.ValueChangedFcn = createCallbackFcn(app, @EndEffectorNameEditFieldValueChanged, true);
            app.EndEffectorNameEditField.Value = 'Gripper';
            % Show the figure after all components are created
            app.WaypointPublisherUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = waypointPublisher

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WaypointPublisherUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WaypointPublisherUIFigure)
        end
    end
end